classdef appMaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                     matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ONE_VR                 matlab.ui.container.Tab
        Label1                 matlab.ui.control.Label
        SubLabel1              matlab.ui.control.Label
        Tabela1                matlab.ui.control.Table
        Calcular1              matlab.ui.control.Button
        Limpar1                matlab.ui.control.Button
        Sair1                  matlab.ui.control.Button
        MtodosDropDownLabel    matlab.ui.control.Label
        Metodos1               matlab.ui.control.DropDown
        aEditFieldLabel        matlab.ui.control.Label
        a1                     matlab.ui.control.NumericEditField
        fxLabel                matlab.ui.control.Label
        f1                     matlab.ui.control.EditField
        hEditFieldLabel        matlab.ui.control.Label
        h1                     matlab.ui.control.NumericEditField
        bEditFieldLabel        matlab.ui.control.Label
        b1                     matlab.ui.control.NumericEditField
        Grafico1               matlab.ui.control.UIAxes
        TWO_VR                 matlab.ui.container.Tab
        Label2                 matlab.ui.control.Label
        SubLabel2              matlab.ui.control.Label
        Tabela2                matlab.ui.control.Table
        Calcular2              matlab.ui.control.Button
        Limpar2                matlab.ui.control.Button
        Sair2                  matlab.ui.control.Button
        Text2                  matlab.ui.control.TextArea
        aEditField_2Label      matlab.ui.control.Label
        a2                     matlab.ui.control.NumericEditField
        fxyEditFieldLabel      matlab.ui.control.Label
        f2                     matlab.ui.control.EditField
        hEditField_2Label      matlab.ui.control.Label
        h2                     matlab.ui.control.NumericEditField
        bEditField_2Label      matlab.ui.control.Label
        b2                     matlab.ui.control.NumericEditField
        MtodosDropDown_2Label  matlab.ui.control.Label
        Metodos2               matlab.ui.control.DropDown
        Grafico2               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Limpar1
        function Limpar1ButtonPushed(app, event)
            cla(app.Grafico1);
            app.Tabela1.Data = {};
            set(app.Tabela1, 'columnname', {});
            legend(app.Grafico1,'boxoff');
            app.a1.Value=0;
            app.b1.Value=0;
            app.h1.Value=0;
            app.f1.Value='';
            app.Text1.Value='';
        end

        % Button pushed function: Limpar2
        function Limpar2ButtonPushed(app, event)
            cla(app.Grafico2);
            app.Tabela2.Data = {};
            set(app.Tabela2, 'columnname', {});
            legend(app.Grafico2,'boxoff');
            app.a2.Value=0;
            app.b2.Value=0;
            app.h2.Value=0;
            app.f2.Value='';
            app.Text2.Value='';
        end

        % Button pushed function: Sair1, Sair2
        function Sair1ButtonPushed(app, event)
            exitMessage = questdlg("Deseja mesmo sair?", "Sair", "Sim", "Não", "Não");
            
            if strcmp(exitMessage, "Sim")
                close(app.UI);
            end
        end

        % Button pushed function: Calcular1
        function Calcular1ButtonPushed(app, event)
            syms f(x) % Criar duas funções simbólicas
            
            cla(app.Grafico1); % Limpar o gráfico
            
            %%%%% Verificação do Input %%%%%
            strF = app.f1.Value; % Obter o valor no campo da função f
            
            f = @(x) eval(vectorize(strF)); % Definir a nossa função f
            try % Testar se a função inserida é válida
                f(x);
            catch
                errordlg("Introduza uma função em x","Erro");
                return
            end
            
            
            a = app.a1.Value; % Obter o valor do campo A
            if (not(isscalar(a) && isreal(a))) % O valor A tem de ser real
                errordlg("O número introduzido para a é inválido","Erro");
                return
            end
            
            b = app.b1.Value; % Obter o valor do campo B
            if (not(isscalar(b) && isreal(b) && b>a)) % O valor B tem de ser real e maior que A
                errordlg("O número introduzido para b é inválido","Erro");
                return
            end
        
            h = app.h1.Value; % Obter o valor do campo H
            if (not(isscalar(h)))
                errordlg("O número introduzido para h é inválido","Erro");
                return
            end
            
            %%%% Cálculo da derivada %%%%%
            d = diff(f,x);
            g = @(x) eval(vectorize(d));
            x = a:h:b;
            plot(app.Grafico1,f(x),'-b','LineWidth',1);
            hold(app.Grafico1, "on");
            plot(app.Grafico1,g(x),'-ro','LineWidth',1);
            hold(app.Grafico1, "on");
            legend(app.Grafico1,'boxon'); %Para prevenir caso limpar
            legend(app.Grafico1,'Função','Exata','Location','northwest'); % Legenda do gráfico
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure('Visible', 'off');
            app.UI.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UI);
            app.ONE_VR = uitab(app.TabGroup);
            app.ONE_VR.Title = 'Função real de 1 variável real: y = f(x)';
            app.Label1 = uilabel(app.ONE_VR);
            app.Label1.Text = 'Função real de 1 variável real';
            app.SubLabel1 = uilabel(app.ONE_VR);
            app.SubLabel1.Text = 'y = f(x)';
            app.Tabela1 = uitable(app.ONE_VR);
            app.Tabela1.ColumnName = {''; ''; ''; ''};
            app.Tabela1.RowName = {};
            app.Calcular1 = uibutton(app.ONE_VR, 'push');
            app.Calcular1.ButtonPushedFcn = createCallbackFcn(app, @Calcular1ButtonPushed, true);
            app.Calcular1.Text = 'Calcular';
            app.Limpar1 = uibutton(app.ONE_VR, 'push');
            app.Limpar1.ButtonPushedFcn = createCallbackFcn(app, @Limpar1ButtonPushed, true);
            app.Limpar1.Text = 'Limpar';
            app.Sair1 = uibutton(app.ONE_VR, 'push');
            app.Sair1.ButtonPushedFcn = createCallbackFcn(app, @Sair1ButtonPushed, true);
            app.Sair1.Text = 'Sair';
            app.MtodosDropDownLabel = uilabel(app.ONE_VR);
            app.MtodosDropDownLabel.Text = 'Métodos';
            app.Metodos1 = uidropdown(app.ONE_VR);
            app.Metodos1.Items = {'DF. Progressivas (2 pontos)', 'DF. Regressivas (2 pontos)', 'DF. Progressivas (3 pontos)', 'DF. Regressivas (3 pontos)', 'DF. Centradas (3 pontos)', 'DF. 2ª Derivada (3 pontos)'};
            app.Metodos1.Value = 'DF. Progressivas (2 pontos)';
            app.aEditFieldLabel = uilabel(app.ONE_VR);
            app.aEditFieldLabel.Text = 'a';
            app.a1 = uieditfield(app.ONE_VR, 'numeric');
            app.fxLabel = uilabel(app.ONE_VR);
            app.fxLabel.Text = 'f(x)';
            app.f1 = uieditfield(app.ONE_VR, 'text');
            app.f1.Value = 'sin(x)';
            app.hEditFieldLabel = uilabel(app.ONE_VR);
            app.hEditFieldLabel.Text = 'h';
            app.h1 = uieditfield(app.ONE_VR, 'numeric');
            app.h1.Value = 0.1;
            app.bEditFieldLabel = uilabel(app.ONE_VR);
            app.bEditFieldLabel.Text = 'b';
            app.b1 = uieditfield(app.ONE_VR, 'numeric');
            app.b1.Value = 6.28318530717959;
            app.Grafico1 = uiaxes(app.ONE_VR);
            zlabel(app.Grafico1, 'Z')
            app.TWO_VR = uitab(app.TabGroup);
            app.TWO_VR.Title = 'Função real de 2 variáveis reais: z = f(x,y)';
            app.Label2 = uilabel(app.TWO_VR);
            app.Label2.Text = 'Função real de 2 variáveis reais';
            app.SubLabel2 = uilabel(app.TWO_VR);
            app.SubLabel2.Text = 'z = f(x,y)';
            app.Tabela2 = uitable(app.TWO_VR);
            app.Tabela2.ColumnName = {''; ''; ''; ''};
            app.Tabela2.RowName = {};
            app.Calcular2 = uibutton(app.TWO_VR, 'push');
            app.Calcular2.Text = 'Calcular';
            app.Limpar2 = uibutton(app.TWO_VR, 'push');
            app.Limpar2.ButtonPushedFcn = createCallbackFcn(app, @Limpar2ButtonPushed, true);
            app.Limpar2.Text = 'Limpar';
            app.Sair2 = uibutton(app.TWO_VR, 'push');
            app.Sair2.ButtonPushedFcn = createCallbackFcn(app, @Sair1ButtonPushed, true);
            app.Sair2.Text = 'Sair';
            app.Text2 = uitextarea(app.TWO_VR);
            app.Text2.Editable = 'off';
            app.aEditField_2Label = uilabel(app.TWO_VR);
            app.aEditField_2Label.Text = 'a';
            app.a2 = uieditfield(app.TWO_VR, 'numeric');
            app.fxyEditFieldLabel = uilabel(app.TWO_VR);
            app.fxyEditFieldLabel.Text = 'f(x,y)';
            app.f2 = uieditfield(app.TWO_VR, 'text');
            app.hEditField_2Label = uilabel(app.TWO_VR);
            app.hEditField_2Label.Text = 'h';
            app.h2 = uieditfield(app.TWO_VR, 'numeric');
            app.bEditField_2Label = uilabel(app.TWO_VR);
            app.bEditField_2Label.Text = 'b';
            app.b2 = uieditfield(app.TWO_VR, 'numeric');
            app.MtodosDropDown_2Label = uilabel(app.TWO_VR);
            app.MtodosDropDown_2Label.Text = 'Métodos';
            app.Metodos2 = uidropdown(app.TWO_VR);
            app.Metodos2.Items = {'DF. Progressivas (2 pontos)', 'DF. Regressivas (2 pontos)', 'DF. Progressivas (3 pontos)', 'DF. Regressivas (3 pontos)', 'DF. Centradas (3 pontos)', 'DF. 2ª Derivada (3 pontos)', 'Todos'};
            app.Metodos2.Value = 'DF. Progressivas (2 pontos)';
            app.Grafico2 = uiaxes(app.TWO_VR);
            zlabel(app.Grafico2, 'Z')
            % Show the figure after all components are created
            app.UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end